classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SzybkoruchuButtonGroup   matlab.ui.container.ButtonGroup
        SzybkiButton             matlab.ui.control.ToggleButton
        redniButton              matlab.ui.control.ToggleButton
        WolnyButton              matlab.ui.control.ToggleButton
        ZakoczkolejkButton       matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        RuchzakolejkowanyLabel   matlab.ui.control.Label
        DodajkrokButton          matlab.ui.control.Button
        ZacznijkolejkButton      matlab.ui.control.Button
        LiczbakrokwSpinner       matlab.ui.control.Spinner
        LiczbakrokwSpinnerLabel  matlab.ui.control.Label
        LabelXYZ                 matlab.ui.control.Label
        LabelKat                 matlab.ui.control.Label
        LabelY                   matlab.ui.control.Label
        LabelZ                   matlab.ui.control.Label
        LabelX                   matlab.ui.control.Label
        LabelKat3stopnie         matlab.ui.control.Label
        LabelKat2stopnie         matlab.ui.control.Label
        LabelKat1stopnie         matlab.ui.control.Label
        ChwytakSwitch            matlab.ui.control.Switch
        ChwytakSwitchLabel       matlab.ui.control.Label
        S2Slider                 matlab.ui.control.Slider
        S2SliderLabel            matlab.ui.control.Label
        LabelKt2Procent          matlab.ui.control.Label
        S3Slider                 matlab.ui.control.Slider
        S3SliderLabel            matlab.ui.control.Label
        LabelKt3Procent          matlab.ui.control.Label
        LabelKt1Procent          matlab.ui.control.Label
        S1Slider                 matlab.ui.control.Slider
        S1SliderLabel            matlab.ui.control.Label
        Model                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % żółty d2
        % zielony d3
        % niebieski d4
        % biały d5
        
        a;
        chwytak;
        lokiec;
        bark;
        podstawa;
        p0;
        p1;
        p2;
        arm1;
        arm2;
        alfa;
        beta;
        gamma;
        delta;
        kolejka;
        licznik_kolejki;
        speed;
%         czas;
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function oblicz(app)
            cla(app.Model)

            %wyliczenie wspolzendych p1 i p2
            %l1=7.9
            %l2=7.9


            app.p0=[0,0,0];
            app.p1=[0,0,0];
            app.p2=[0,0,0];
            app.p1(3)=7.9*sind(app.beta);
            app.p1(1)=7.9*cosd(app.beta)*cosd(app.gamma);
            app.p1(2)=7.9*cosd(app.beta)*sind(app.gamma);
            app.p2(3)=app.p1(3)-7.9*cosd(90-app.alfa);
            app.p2(1)=app.p1(1)+7.9*sind(90-app.alfa)*cosd(app.gamma);
            app.p2(2)=app.p1(2)+7.9*sind(90-app.alfa)*sind(app.gamma);

            %stworzenie lini prostych
            app.arm1=[app.p0;app.p1];
            app.arm2=[app.p1;app.p2];

            %wypisanie wspolzednych p2
            app.LabelX.Text=string(round(app.p2(1),2));
            app.LabelZ.Text=string(round(app.p2(3),2));
            app.LabelY.Text=string(round(app.p2(2),2));

            %narysowanie p0,p1,p2
            %axis([-16 16 -16 16 -16 16]);
            app.Model.XLim=[-14 14];
            app.Model.YLim=[-14 14];
            app.Model.ZLim=[0 14];
            view(app.Model,[16 16 16]);
            plot3(app.Model,app.arm1(:,1),app.arm1(:,2),app.arm1(:,3));
            plot3(app.Model,app.arm2(:,1),app.arm2(:,2),app.arm2(:,3));
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino("COM6",'Uno');
            app.chwytak=servo(app.a,"D2");
            app.lokiec=servo(app.a,"D3");
            app.bark=servo(app.a,"D4");
            app.podstawa=servo(app.a,"D5");

            app.alfa=45;
            app.beta=90;
            app.delta=0.6;
            app.gamma=0;
            writePosition(app.chwytak, app.delta)
            writePosition(app.lokiec, 0.375)
            writePosition(app.bark, 0.7)
            writePosition(app.podstawa, 0)

            app.speed=15;

            oblicz(app);

%           app.alfa=readPositon(app.lokiec)*10000/70;
%           app.beta=(readPositon(app.bark)-42)*10000/(100-42);
%           app.gamma=readPositon(app.podstawa)*10000/100;
            app.LabelKat1stopnie.Text=string(round(app.alfa,2)+"°");
            app.LabelKat2stopnie.Text=string(round(app.beta,2)+"°");
            app.LabelKat3stopnie.Text=string(round(app.gamma,2)+"°");
        end

        % Value changing function: S1Slider
        function S1SliderValueChanging(app, event)
            %Zakres serwa(0-75)
            changingValue = event.Value;
            app.alfa=90*65/55-90*changingValue/55;
            writePosition(app.lokiec, changingValue/100)

            app.LabelKt1Procent.Text=string(round(changingValue,2));
            app.LabelKat1stopnie.Text=string(round(app.alfa,2)+"°");

            oblicz(app)

            pause(0.10);

        end

        % Value changing function: S2Slider
        function S2SliderValueChanging(app, event)
            %Zakres serwa(40-100)
            changingValue = event.Value;
            app.beta=195-3*changingValue/2;
            writePosition(app.bark, changingValue/100)

            app.LabelKt2Procent.Text=string(round(changingValue,2));
            app.LabelKat2stopnie.Text=string(round(app.beta,2)+"°");

            oblicz(app)

            pause(0.10);
            
        end

        % Value changing function: S3Slider
        function S3SliderValueChanging(app, event)
            %Zakres serwa(0-100)
            changingValue = event.Value;
            app.gamma=changingValue*1.8;
            writePosition(app.podstawa, changingValue/100)

            app.LabelKt3Procent.Text=string(round(changingValue,2));
            app.LabelKat3stopnie.Text=string(round(app.gamma,2)+"°");

            oblicz(app)

            %pause(0.10);

        end

        % Value changed function: ChwytakSwitch
        function ChwytakSwitchValueChanged(app, event)
            value = app.ChwytakSwitch.Value;
            if value=="Open"
                app.delta=0.75;
                writePosition(app.chwytak, app.delta)
            end
            if value=="Close"
                app.delta=0.4;
                writePosition(app.chwytak, app.delta)
            end
            %pause(0.10);
        end

        % Button pushed function: ZacznijkolejkButton
        function ZacznijkolejkButtonPushed(app, event)
            app.kolejka=zeros(4,app.LiczbakrokwSpinner.Value);
            app.licznik_kolejki=0;
            set(app.LiczbakrokwSpinner,'Enable','off');
            set(app.ZacznijkolejkButton,'Enable','on');
            set(app.DodajkrokButton,'Enable','on');
            set(app.StartButton,'Enable','on');
            set(app.ZakoczkolejkButton,'Enable','on');
        end

        % Button pushed function: ZakoczkolejkButton
        function ZakoczkolejkButtonPushed(app, event)
            set(app.LiczbakrokwSpinner,'Enable','on');
            set(app.StartButton,'Enable','off');
            set(app.DodajkrokButton,'Enable','off');
            set(app.ZakoczkolejkButton,'Enable','off');

        end

        % Button pushed function: DodajkrokButton
        function DodajkrokButtonPushed(app, event)
            app.licznik_kolejki=app.licznik_kolejki+1;
            app.kolejka(app.licznik_kolejki,1)=app.alfa;
            app.kolejka(app.licznik_kolejki,2)=app.beta;
            app.kolejka(app.licznik_kolejki,3)=app.gamma;
            app.kolejka(app.licznik_kolejki,4)=app.delta;
            if(app.licznik_kolejki==app.LiczbakrokwSpinner.Value)
                set(app.DodajkrokButton,'Enable','off');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set(app.ChwytakSwitch,'Enable','off');
            set(app.S1Slider,'Enable','off');
            set(app.S2Slider,'Enable','off');
            set(app.S3Slider,'Enable','off');

            for index = 1:app.licznik_kolejki
                dalfa=(app.kolejka(index,1)-app.alfa)/app.speed;
                dbeta=(app.kolejka(index,2)-app.beta)/app.speed;
                dgamma=(app.kolejka(index,3)-app.gamma)/app.speed;
                for i = 0:1:app.speed
                    writePosition(app.lokiec, (65-55*(app.alfa+dalfa*i)/90)/100)
                    writePosition(app.bark, (195*2/3-2*(app.beta+dbeta*i)/3)/100)
                    writePosition(app.podstawa, (app.gamma+dgamma*i)/180)

%                     app.czas((app.licznik_kolejki-1)+i*app.speed)=toc;
                end
                writePosition(app.chwytak, app.kolejka(index,4))
                app.alfa=app.kolejka(index,1);
                app.beta=app.kolejka(index,2);
                app.gamma=app.kolejka(index,3);
                app.delta=app.kolejka(index,4);
                app.oblicz()
                pause(0.4)
            end
            
%             set(app.ChwytakSwitch,'Enable','on');
            set(app.S1Slider,'Enable','on');
            set(app.S2Slider,'Enable','on');
            set(app.S3Slider,'Enable','on');
%            set(app.StartButtonPushed,'Enable','on');
        end

        % Selection changed function: SzybkoruchuButtonGroup
        function SzybkoruchuButtonGroupSelectionChanged(app, event)
            if app.WolnyButton.Value==1
                app.speed=15;
            elseif app.redniButton.Value==1
                app.speed=5;
            else
                app.speed=2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Model = uiaxes(app.UIFigure);
            title(app.Model, 'Model')
            xlabel(app.Model, 'X')
            ylabel(app.Model, 'Y')
            zlabel(app.Model, 'Z')
            app.S1SliderLabel = uilabel(app.UIFigure);
            app.S1SliderLabel.Text = 'S1';
            app.S1Slider = uislider(app.UIFigure);
            app.S1Slider.Limits = [0 75];
            app.S1Slider.ValueChangingFcn = createCallbackFcn(app, @S1SliderValueChanging, true);
            app.S1Slider.Value = 37.5;
            app.LabelKt1Procent = uilabel(app.UIFigure);
            app.LabelKt1Procent.Text = 'Kąt1';
            app.LabelKt3Procent = uilabel(app.UIFigure);
            app.LabelKt3Procent.Text = 'Kąt3';
            app.S3SliderLabel = uilabel(app.UIFigure);
            app.S3SliderLabel.Text = 'S3';
            app.S3Slider = uislider(app.UIFigure);
            app.S3Slider.ValueChangingFcn = createCallbackFcn(app, @S3SliderValueChanging, true);
            app.LabelKt2Procent = uilabel(app.UIFigure);
            app.LabelKt2Procent.Text = 'Kąt2';
            app.S2SliderLabel = uilabel(app.UIFigure);
            app.S2SliderLabel.Text = 'S2';
            app.S2Slider = uislider(app.UIFigure);
            app.S2Slider.Limits = [40 100];
            app.S2Slider.ValueChangingFcn = createCallbackFcn(app, @S2SliderValueChanging, true);
            app.S2Slider.Value = 70;
            app.ChwytakSwitchLabel = uilabel(app.UIFigure);
            app.ChwytakSwitchLabel.Text = 'Chwytak';
            app.ChwytakSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChwytakSwitch.Items = {'Close', 'Open'};
            app.ChwytakSwitch.ValueChangedFcn = createCallbackFcn(app, @ChwytakSwitchValueChanged, true);
            app.ChwytakSwitch.Value = 'Open';
            app.LabelKat1stopnie = uilabel(app.UIFigure);
            app.LabelKat1stopnie.Text = 'Kąt1';
            app.LabelKat2stopnie = uilabel(app.UIFigure);
            app.LabelKat2stopnie.Text = 'Kąt2';
            app.LabelKat3stopnie = uilabel(app.UIFigure);
            app.LabelKat3stopnie.Text = 'Kąt3';
            app.LabelX = uilabel(app.UIFigure);
            app.LabelX.Text = 'X';
            app.LabelZ = uilabel(app.UIFigure);
            app.LabelZ.Text = 'Z';
            app.LabelY = uilabel(app.UIFigure);
            app.LabelY.Text = 'Y';
            app.LabelKat = uilabel(app.UIFigure);
            app.LabelKat.Text = 'Pozycja chwytaka (alfa,beta,gamma)';
            app.LabelXYZ = uilabel(app.UIFigure);
            app.LabelXYZ.Text = 'Pozycja chwytaka (X,Y,Z)';
            app.LiczbakrokwSpinnerLabel = uilabel(app.UIFigure);
            app.LiczbakrokwSpinnerLabel.Text = 'Liczba kroków';
            app.LiczbakrokwSpinner = uispinner(app.UIFigure);
            app.LiczbakrokwSpinner.Limits = [2 10];
            app.LiczbakrokwSpinner.Value = 2;
            app.ZacznijkolejkButton = uibutton(app.UIFigure, 'push');
            app.ZacznijkolejkButton.ButtonPushedFcn = createCallbackFcn(app, @ZacznijkolejkButtonPushed, true);
            app.ZacznijkolejkButton.Text = 'Zacznij kolejkę';
            app.DodajkrokButton = uibutton(app.UIFigure, 'push');
            app.DodajkrokButton.ButtonPushedFcn = createCallbackFcn(app, @DodajkrokButtonPushed, true);
            app.DodajkrokButton.Enable = 'off';
            app.DodajkrokButton.Text = 'Dodaj krok';
            app.RuchzakolejkowanyLabel = uilabel(app.UIFigure);
            app.RuchzakolejkowanyLabel.Text = 'Ruch zakolejkowany';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.ZakoczkolejkButton = uibutton(app.UIFigure, 'push');
            app.ZakoczkolejkButton.ButtonPushedFcn = createCallbackFcn(app, @ZakoczkolejkButtonPushed, true);
            app.ZakoczkolejkButton.Enable = 'off';
            app.ZakoczkolejkButton.Text = 'Zakończ kolejkę';
            app.SzybkoruchuButtonGroup = uibuttongroup(app.UIFigure);
            app.SzybkoruchuButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SzybkoruchuButtonGroupSelectionChanged, true);
            app.SzybkoruchuButtonGroup.Title = 'Szybkość ruchu';
            app.WolnyButton = uitogglebutton(app.SzybkoruchuButtonGroup);
            app.WolnyButton.Text = 'Wolny';
            app.WolnyButton.Value = true;
            app.redniButton = uitogglebutton(app.SzybkoruchuButtonGroup);
            app.redniButton.Text = 'Średni';
            app.SzybkiButton = uitogglebutton(app.SzybkoruchuButtonGroup);
            app.SzybkiButton.Text = 'Szybki';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end